classdef NeuralBrowser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ViewdataPanel                 matlab.ui.container.Panel
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
        UITable                       matlab.ui.control.Table
        ChannelSliderLabel            matlab.ui.control.Label
        ChannelSlider                 matlab.ui.control.Slider
        TrialSliderLabel              matlab.ui.control.Label
        TrialSlider                   matlab.ui.control.Slider
        ParametersPanel               matlab.ui.container.Panel
        KnobLabel                     matlab.ui.control.Label
        Knob                          matlab.ui.control.Knob
        Gauge_2Label                  matlab.ui.control.Label
        Gauge_2                       matlab.ui.control.LinearGauge
        TabGroup                      matlab.ui.container.TabGroup
        ExtractdataTab                matlab.ui.container.Tab
        NeualLoadButton               matlab.ui.control.Button
        SelectblocktoextractLabel     matlab.ui.control.Label
        NeuralBrowseButton            matlab.ui.control.Button
        NeuralpathEditFieldLabel      matlab.ui.control.Label
        NeuralPathEditField           matlab.ui.control.EditField
        Label                         matlab.ui.control.Label
        NeuralLamp                    matlab.ui.control.Lamp
        ExtractionandfilteringLabel   matlab.ui.control.Label
        NeuralGauge                   matlab.ui.control.LinearGauge
        LoaddataTab                   matlab.ui.container.Tab
        BehavLoadButton               matlab.ui.control.Button
        SelectbehaviouralfileLabel    matlab.ui.control.Label
        BehavBrowseButton             matlab.ui.control.Button
        BehaviouralLabel              matlab.ui.control.Label
        BehaviouralEditField          matlab.ui.control.EditField
        Lamp_3Label                   matlab.ui.control.Label
        BehaviouralLamp               matlab.ui.control.Lamp
        BlockDropDownLabel            matlab.ui.control.Label
        BlockDropDown                 matlab.ui.control.DropDown
        LoadingandPreprocessingLabel  matlab.ui.control.Label
        BlockGauge                    matlab.ui.control.LinearGauge
    end


    properties (Access = private)
        behavPath % Temp storage for fullpath for behavioural file
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Populate available blocks in processing folder
            fn = [pwd, '\Preprocessing\*block*'];
            blocks = dir(fn);
            for b = 1:numel(blocks)
                app.BlockDropDown.Items{b} = blocks(b).name;
            end
            
        end

        % Button pushed function: NeualLoadButton
        function NeualLoadButtonPushed(app, event)
            % Extract specified directory to \Extracted\ using TDTHelper
            % Process using Neural
            
            % Reset lamp and gauge
            app.NeuralLamp.Color = [0.9, 0.9, 0.2];
            app.NeuralGauge.Value = 0;
            
            % Try and find folder in box
            fn = app.NeuralPathEditField.Value;
            
            % Run exist checks
            if ~exist(fn, 'dir')
                app.NeuralLamp.Color = [0.9, 0, 0];
                return
            end
            
            % Try and get block name
            fps = string(fn).split('\');
            block = fps(end);
            
            if block.lower().contains('block')
                % Assume valid dir
                fn = [fn, '\'];
            else
                app.NeuralLamp.Color = [0.9, 0, 0];
                return
            end
            
            % Generate an outPath
            outPath = [pwd, '\Extracted\', block.char(), '\'];
            
            % Create TDTHelper object
            TDT = TDTHelper(fn, outPath, false);
            ok = TDT.runExtraction();
            fs = 24400;
            
            app.NeuralGauge.Value = 20;
            
            if ~ok
                app.NeuralLamp.Color = [0.9, 0, 0];
                return
            end
            
            % Begin processing using NeuralPP library
            % Filter
            % Save to \Preprecessing\Block
            
            path = [pwd, '\Preprocessing\', block.char(),'\'];
            if ~exist(path, 'dir')
                mkdir(path)
            end
            
            fn = [path, 'BB2.mat'];
            if exist(fn, 'file')
                % Skip BB2
                disp('BB2 already done, skipping.')
                app.NeuralGauge.Value = 60;
            else
                BB2 = TDT.loadEvID('BB_2');
                app.NeuralGauge.Value = 30;
                
                [fData, lfpData] = NeuralPP.filter(BB2, fs);
                app.NeuralGauge.Value = 50;
                
                save(fn, 'fData', 'lfpData')
                app.NeuralGauge.Value = 60;
            end
            clear BB2
            
            fn = [path, 'BB3.mat'];
            if exist(fn, 'file')
                % Skip BB2
                disp('BB3 already done, skipping.')
                app.NeuralGauge.Value = 100;
            else
                BB3 = TDT.loadEvID('BB_3');
                app.NeuralGauge.Value = 70;
                
                [fData, lfpData] = NeuralPP.filter(BB3, fs);
                app.NeuralGauge.Value = 90;
                
                save(fn, 'fData', 'lfpData')
                app.NeuralGauge.Value = 100;
                
            end
            clear BB3
            
            % Update lamp
            app.NeuralLamp.Color = [0, 0.9, 0];
            
            % Add block to available block list
            if ~string(app.BlockDropDown.Items).contains(block)
                % If not already in list
                nItems = numel(app.BlockDropDown.Items);
                app.BlockDropDown.Items{nItems} = block.char();
            end
        end

        % Button pushed function: NeuralBrowseButton
        function NeuralBrowseButtonPushed(app, event)
            fn = uigetdir;
            if ischar(fn)
                app.NeuralPathEditField.Value = fn;
            end
        end

        % Button pushed function: BehavBrowseButton
        function BehavBrowseButtonPushed(app, event)
            [fn, pn] = uigetfile;
            if ischar(fn)
                app.BehaviouralEditField.Value = fn;
                app.behavPath = pn;
            end
        end

        % Button pushed function: BehavLoadButton
        function BehavLoadButtonPushed(app, event)
            
            % Reset lamp and gauge
            app.BehaviouralLamp.Color = [0.9, 0.9, 0.2];
            app.BlockGauge.Value = 0;
            
            % Check behavioural file matches block
            block = string(app.BlockDropDown.Value);
            bFn = string(app.BehaviouralEditField.Value);
            
            if ~bFn.contains(block)
                app.NeuralLamp.Color = [0.9, 0, 0];
            end
            
            % Load behavioural file
            data = load([app.behavPath, bFn.char()]);
            data = data.saveData(2:end);
            
            % Do basic processing of behav data to table
            nTrials = numel(data);
            vars = {NaN(nTrials,1), 'TT', ''; ...
                NaN(nTrials,1), 'repeatMode', ''; ...
                NaN(nTrials,1), 'side', ''; ...
                NaN(nTrials,1), 'resp', ''; ...
                NaN(nTrials,1), 'aNoise', ''; ...
                NaN(nTrials,1), 'vNoise', ''; ...
                NaN(nTrials,1), 'gap1', ''; ...
                NaN(nTrials,1), 'gap2', ''; ...
                NaN(nTrials,1), 'duration', ''; ...
                NaN(nTrials,1), 'aysncOffset', 'Note typo'; ...
                cell(nTrials,1), 'eventType', ''; ...
                NaN(nTrials,2), 'speakers', ''};
            nVars = size(vars,1);
            disp(vars)
            
            trials = table(vars{:,1});
            trials.Properties.VariableNames = vars(:,2);
            trials.Properties.VariableDescriptions = vars(:,3);
            
            for n = 1:nTrials
                disp(n)
                for v = 1:nVars
                    if isa(data{n}.(vars{v,2}), 'char')
                        trials.(vars{v,2}){n} = data{n}.(vars{v,2});
                    else
                        trials.(vars{v,2})(n,:) = data{n}.(vars{v,2});
                    end
                end
            end
            
            % Check if neural data already processed to spikes
            
            % If not, load processed data
            
            % Epoch
            
            % Clean
            
            % Save to \Spikes\
            
            % Run channel selector callback
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.ViewdataPanel = uipanel(app.UIFigure);
            app.ViewdataPanel.Title = 'View data';
            app.UIAxes2 = uiaxes(app.ViewdataPanel);
            title(app.UIAxes2, 'Spikes');
            xlabel(app.UIAxes2, 'X');
            ylabel(app.UIAxes2, 'Y');
            app.UIAxes = uiaxes(app.ViewdataPanel);
            title(app.UIAxes, 'Filtered trace');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            app.UITable = uitable(app.ViewdataPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.ChannelSliderLabel = uilabel(app.ViewdataPanel);
            app.ChannelSliderLabel.Text = 'Channel';
            app.ChannelSlider = uislider(app.ViewdataPanel);
            app.ChannelSlider.Limits = [0 16];
            app.ChannelSlider.MajorTickLabels = {'1', '2', '4', '6', '8', '10', '12', '14', '16'};
            app.TrialSliderLabel = uilabel(app.ViewdataPanel);
            app.TrialSliderLabel.Text = 'Trial';
            app.TrialSlider = uislider(app.ViewdataPanel);
            app.TrialSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.ParametersPanel = uipanel(app.UIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.KnobLabel = uilabel(app.ParametersPanel);
            app.KnobLabel.Text = 'Knob';
            app.Knob = uiknob(app.ParametersPanel, 'continuous');
            app.Gauge_2Label = uilabel(app.UIFigure);
            app.Gauge_2Label.Text = '';
            app.Gauge_2 = uigauge(app.UIFigure, 'linear');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ExtractdataTab = uitab(app.TabGroup);
            app.ExtractdataTab.Title = 'Extract data';
            app.ExtractdataTab.Units = 'pixels';
            app.NeualLoadButton = uibutton(app.ExtractdataTab, 'push');
            app.NeualLoadButton.ButtonPushedFcn = createCallbackFcn(app, @NeualLoadButtonPushed, true);
            app.NeualLoadButton.Text = 'Extract and filter';
            app.SelectblocktoextractLabel = uilabel(app.ExtractdataTab);
            app.SelectblocktoextractLabel.Text = 'Select block to extract';
            app.NeuralBrowseButton = uibutton(app.ExtractdataTab, 'push');
            app.NeuralBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @NeuralBrowseButtonPushed, true);
            app.NeuralBrowseButton.Text = ':';
            app.NeuralpathEditFieldLabel = uilabel(app.ExtractdataTab);
            app.NeuralpathEditFieldLabel.Text = 'Neural path';
            app.NeuralPathEditField = uieditfield(app.ExtractdataTab, 'text');
            app.Label = uilabel(app.ExtractdataTab);
            app.Label.Text = '';
            app.NeuralLamp = uilamp(app.ExtractdataTab);
            app.ExtractionandfilteringLabel = uilabel(app.ExtractdataTab);
            app.ExtractionandfilteringLabel.Text = 'Extraction and filtering';
            app.NeuralGauge = uigauge(app.ExtractdataTab, 'linear');
            app.LoaddataTab = uitab(app.TabGroup);
            app.LoaddataTab.Title = 'Load data';
            app.LoaddataTab.Units = 'pixels';
            app.BehavLoadButton = uibutton(app.LoaddataTab, 'push');
            app.BehavLoadButton.ButtonPushedFcn = createCallbackFcn(app, @BehavLoadButtonPushed, true);
            app.BehavLoadButton.Text = 'Load and PP';
            app.SelectbehaviouralfileLabel = uilabel(app.LoaddataTab);
            app.SelectbehaviouralfileLabel.Text = 'Select behavioural file';
            app.BehavBrowseButton = uibutton(app.LoaddataTab, 'push');
            app.BehavBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BehavBrowseButtonPushed, true);
            app.BehavBrowseButton.Text = ':';
            app.BehaviouralLabel = uilabel(app.LoaddataTab);
            app.BehaviouralLabel.Text = 'Behavioural ';
            app.BehaviouralEditField = uieditfield(app.LoaddataTab, 'text');
            app.Lamp_3Label = uilabel(app.LoaddataTab);
            app.Lamp_3Label.Text = '';
            app.BehaviouralLamp = uilamp(app.LoaddataTab);
            app.BlockDropDownLabel = uilabel(app.LoaddataTab);
            app.BlockDropDownLabel.Text = 'Block';
            app.BlockDropDown = uidropdown(app.LoaddataTab);
            app.BlockDropDown.Items = {};
            app.BlockDropDown.Value = {};
            app.LoadingandPreprocessingLabel = uilabel(app.LoaddataTab);
            app.LoadingandPreprocessingLabel.Text = 'Loading and Preprocessing';
            app.BlockGauge = uigauge(app.LoaddataTab, 'linear');
        end
    end

    methods (Access = public)

        % Construct app
        function app = NeuralBrowser()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end